classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AboutButton         matlab.ui.control.Button
        RadarPlotButton     matlab.ui.control.Button
        HelpButton          matlab.ui.control.Button
        TextArea_3          matlab.ui.control.TextArea
        Image_3             matlab.ui.control.Image
        Image_2             matlab.ui.control.Image
        TextArea_2          matlab.ui.control.TextArea
        TextArea            matlab.ui.control.TextArea
        Image               matlab.ui.control.Image
        TimeScope_4         matlab.ui.scope.TimeScope
        TimeScope_3         matlab.ui.scope.TimeScope
        TimeScope_2         matlab.ui.scope.TimeScope
        SimulationProgress  simulink.ui.control.SimulationProgress
        SimulationControls  simulink.ui.control.SimulationControls
        TimeScope           matlab.ui.scope.TimeScope
        VariableTuner       simulink.ui.control.VariableTuner
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)

    end

    properties (Access = private)
    BaseFigSize (1,2) double
    BasePos = struct()
    LeftRightEdge double
    Margin double
    HelpDy double 
    DataCheckTimer timer
    end

    
    methods (Access = private)
        
     function updateRadarButtonState(app)

    % --- 1) base workspace 检查 ---
    hasBase = evalin('base', ...
        "exist('Te','var') && exist('ia','var') && exist('CA1','var')");

    % --- 2) SimulationOutput / Output 检查 ---
    hasOut = false;
    out = [];

    try
        if isprop(app.Simulation,"SimulationOutput")
            out = app.Simulation.SimulationOutput;
        elseif isprop(app.Simulation,"Output")
            out = app.Simulation.Output;
        end

        if ~isempty(out)
            % 注意：SimulationOutput 里通常是属性（isprop），不是 isfield
            hasOut = isprop(out,"Te") && isprop(out,"ia") && isprop(out,"CA1");
        end
    catch
        hasOut = false;
    end

    hasData = hasBase || hasOut;

    if hasData
        app.RadarPlotButton.Enable = 'on';
        app.RadarPlotButton.BackgroundColor = [1.00 0.75 0.10];
        app.RadarPlotButton.Tooltip = "Plot radar chart";
    else
        app.RadarPlotButton.Enable = 'off';
        app.RadarPlotButton.BackgroundColor = [0.85 0.85 0.85];
        app.RadarPlotButton.Tooltip = "Run the Simulink simulation first";
    end
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        %% timer check
        % Clear old simulation data in base workspace (force one fresh run)
evalin('base', "clear Te ia CA1");

        % Refresh button state once at startup
app.updateRadarButtonState();

% Create and start a timer to monitor workspace data
app.DataCheckTimer = timer( ...
    'ExecutionMode', 'fixedSpacing', ...
    'Period', 0.5, ...
    'TimerFcn', @(~,~) app.updateRadarButtonState());

start(app.DataCheckTimer);
        %%
            app.UIFigure.AutoResizeChildren = 'off';
    drawnow;
    app.HelpButton.Tooltip  = "Explain parameters and usage of this toolbox.";
    app.AboutButton.Tooltip = ...
"Explain radar score definitions and normalization rules.";
    

    % 图片显示模式
    try
        app.Image.ScaleMethod = 'fit';
        app.Image_2.ScaleMethod = 'fit';
        app.Image_3.ScaleMethod = 'fit';
    catch
    end

    % 基准尺寸
    app.BaseFigSize = app.UIFigure.Position(3:4);
    app.Margin = 20;

    % 记录左侧区域边界（基于初始 VariableTuner）
    app.LeftRightEdge = app.VariableTuner.Position(1) + app.VariableTuner.Position(3);

    % 记录需要“缩放/响应式”的组件基准位置
    app.BasePos = struct();
    app.BasePos.VariableTuner      = app.VariableTuner.Position;
    app.BasePos.SimulationControls = app.SimulationControls.Position;
    app.BasePos.SimulationProgress = app.SimulationProgress.Position;

    app.BasePos.TimeScope   = app.TimeScope.Position;
    app.BasePos.TimeScope_2 = app.TimeScope_2.Position;
    app.BasePos.TimeScope_3 = app.TimeScope_3.Position;
    app.BasePos.TimeScope_4 = app.TimeScope_4.Position;

    % 记录底部主图基准（只用于宽高参考）
    app.BasePos.Image = app.Image.Position;

    % 记录“锚点定位”的组件尺寸（宽高用基准，位置每次重算）
    app.BasePos.Image_2 = app.Image_2.Position;      % logo2
    app.BasePos.Image_3 = app.Image_3.Position;      % logo3
    app.BasePos.TextArea_2 = app.TextArea_2.Position;% 标题
    app.BasePos.HelpButton = app.HelpButton.Position;% Help
    app.BasePos.RadarPlotButton = app.RadarPlotButton.Position;
    app.BasePos.TextArea   = app.TextArea.Position;   % Version
    app.BasePos.TextArea_3 = app.TextArea_3.Position; % CA说明
    app.BasePos.AboutButton = app.AboutButton.Position; % About

 
            app.UIFigure.AutoResizeChildren = 'off';
    drawnow;


        end

        % Callback function
        function FFTButtonPushed(app, event)


    % 模型名称
    mdl = app.Simulation.ModelName;
    load_system(mdl);

    % 查找 Powergui（你已经验证 MaskType = PSB option menu block）
    pg = find_system(mdl, ...
        'LookUnderMasks','all', ...
        'FollowLinks','on', ...
        'MaskType','PSB option menu block', ...
        'MatchFilter', @Simulink.match.allVariants);

    if isempty(pg)
        uialert(app.UIFigure,"Powergui not found in model!","Error");
        return;
    end

    % 打开 FFT Analyzer
    try
        powergui(pg{1}, 'FFTAnalysis');
    catch ME
        uialert(app.UIFigure, ME.message, "FFT Error");
    end


        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            msg = sprintf([ ...
    'PMSM Control Dashboard – User Guide\n\n', ...
    '1. Purpose\n', ...
    '  This App provides a graphical interface for simulating and tuning\n', ...
    '  PMSM control strategies using a Simulink model. It allows you to:\n', ...
    '    • Modify motor and machine parameters\n', ...
    '    • Select controller type (PI or DPC)\n', ...
    '    • Visualize real-time responses (speed, torque, currents)\n', ...
    '    • Run simulations directly from the App\n\n', ...
    '2. Control Mode Selection\n', ...
    '  • CA = 0 : PI control (conventional cascaded control)\n', ...
    '  • CA = 1 : DPC predictive control (AI/DPC-based)\n\n', ...
    '3. Parameter Description\n', ...
    '  The pane on the left contains the machine and control parameters:\n\n', ...
    '  Motor & system parameters:\n', ...
    '    • J       : Rotor inertia [kg·m²]\n', ...
    '    • F       : Viscous friction coefficient\n', ...
    '    • Ld/Lq   : d-axis and q-axis inductances [H]\n', ...
    '    • Ls      : Stator inductance (optional helper value)\n', ...
    '    • Rs      : Stator resistance [Ω]\n', ...
    '    • pn      : Number of pole pairs\n', ...
    '    • phi     : Permanent magnet flux linkage [Wb]\n', ...
    '    • Vdc     : DC link voltage [V]\n', ...
    '    • fsw     : Switching frequency [Hz]\n\n', ...
    '  Control-related inputs:\n', ...
    '    • Tprofile   : External load torque profile selector\n', ...
    '    • Wmprofile  : Speed reference profile selector\n\n', ...
    '4. Simulation Controls\n', ...
    '  • Run  : Starts the Simulink model simulation\n', ...
    '  • Stop : Terminates the running simulation\n', ...
    '  • Time display shows real-time simulation progress\n\n', ...
    '5. Output Scopes\n', ...
    '  The dashboard plots the following signals:\n', ...
    '    • Wm   : Rotor electrical speed [rad/s]\n', ...
    '    • Torque: Electromagnetic torque [N·m]\n', ...
    '    • Id   : d-axis current [A]\n', ...
    '    • Iq   : q-axis current [A]\n\n', ...
    '6. Model Diagram\n', ...
    '  The bottom figure shows a snapshot of the Simulink control model.\n', ...
    '  It helps the user understand the structure:\n', ...
    '    • Power converter module\n', ...
    '    • PMSM electrical model\n', ...
    '    • Mechanical dynamics model\n', ...
    '    • PI/DPC controller block\n', ...
    '    • Measurement and scope blocks\n\n', ...
    '7. Typical Workflow\n', ...
    '  1) Set motor parameters (J, Ld/Lq, Rs, phi, pn)\n', ...
    '  2) Choose control mode (CA = 0 for PI, 1 for MPC, 2 for DPC)\n', ...
    '  3) Select speed/load profile (Wmprofile, Tprofile)\n', ...
    '  4) Click Run to start the simulation\n', ...
    '  5) Observe speed, torque and current waveforms\n', ...
    '  6) Adjust parameters and repeat if necessary\n\n', ...
    '8. Notes\n', ...
    '  • Make sure the Simulink model is on the MATLAB path.\n', ...
    '  • If parameters cause instability, reduce load torque or speed step.\n', ...
    '  • Large fsw reduces ripple but increases simulation time.\n\n', ...
    'Version 1.0   (2025-11)\n', ...
    'Author: Yuan Li\n' ...
]);
uialert(app.UIFigure, msg, 'PMSM Control Dashboard – Help', 'Icon','info');
        end

        % Button pushed function: RadarPlotButton
        function RadarPlotButtonPushed(app, event)

    % try
    %     plot_radar_wrapper();
    %     uialert(app.UIFigure, '完成', '成功');
    % catch ME
    %     uialert(app.UIFigure, ME.message, '错误');
    % end
    % 
    % try
    % 
    %     %%
    %      if evalin('base', 'exist(''CA1'', ''var'')')
    %         app.CA1 = evalin('base', 'CA1');
    %         fprintf('加载CA1成功\n');
    %     else
    %         uialert(app.UIFigure, 'workspace中没有CA1变量', '错误');
    %         return;
    %     end
    % 
    %     % 检查并加载Te
    %     if evalin('base', 'exist(''Te'', ''var'')')
    %         app.Te = evalin('base', 'Te');
    %         fprintf('加载Te成功\n');
    %     else
    %         uialert(app.UIFigure, 'workspace中没有Te变量', '错误');
    %         return;
    %     end
    % 
    %     % 检查并加载ia
    %     if evalin('base', 'exist(''ia'', ''var'')')
    %         app.ia = evalin('base', 'ia');
    %         fprintf('加载ia成功\n');
    %     else
    %         uialert(app.UIFigure, 'workspace中没有ia变量', '错误');
    %         return;
    %     end
    % 
    % catch ME
    %     uialert(app.UIFigure, sprintf('加载数据失败:\n%s', ME.message), '错误');
    %     return;
    % end
    % 
    % % 2. 现在执行计算和绘图
    % try
    %     % 将数据传递到base workspace（给函数使用）
    %     assignin('base', 'CA1', app.CA1);
    %     assignin('base', 'Te', app.Te);
    %     assignin('base', 'ia', app.ia);
    % 
    %     % 计算性能指标
    %     out = calc_metrics_one_run(app.CA1, app.Te, app.ia);
    % 
    %     % 绘制雷达图
    %     draw_radar_from_workspace();
    % 
    %     % 显示成功消息
    %     uialert(app.UIFigure, '雷达图绘制成功！', '成功', 'Icon', 'success');
    %     %%
    %     % 2) 先算当前这次的 OS/ST/THD 并写入 base workspace
    %     calc_metrics_one_run(CA1, Te, ia);
    % 
    %     % 3) 再从 base workspace 汇总画雷达图（如果只跑了一个算法，就画一个）
    %     draw_radar_from_workspace();
    % 
    % catch ME
    %     uialert(app.UIFigure, sprintf("Radar plot failed:\n%s", ME.message), ...
    %         'Radar Error','Icon','error');
    % end
    app.updateRadarButtonState();

if strcmp(app.RadarPlotButton.Enable, 'off')
    uialert(app.UIFigure, ...
        "Please run the Simulink simulation first. No data found in the workspace.", ...
        "Simulation Required", "Icon", "warning");
    return;
end
  %% to work space

              % 1) 确保仿真已经停止
st = get_param(app.Simulation.ModelName, "SimulationStatus");
if ~strcmpi(st,"stopped")
    error("Simulation is not stopped yet.");
end

% 2) 尝试从 Simulation 对象拿到输出（不同版本字段名可能不同）
out = [];
if isprop(app.Simulation, "SimulationOutput")
    out = app.Simulation.SimulationOutput;
elseif isprop(app.Simulation, "Output")
    out = app.Simulation.Output;
end

% 3) 从 out 中取 To Workspace 的变量，再写回 base
% （字段名是否存在取决于你的设置/版本）
try, assignin("base","Te",  out.Te);  end
try, assignin("base","ia",  out.ia);  end
try, assignin("base","CA1", out.CA1); end


  %%


   try
        % === 核心代码：调用你的两个函数 ===
        
        % 1. 从workspace获取数据
        CA1 = evalin('base', 'CA1');
        Te = evalin('base', 'Te');
        ia = evalin('base', 'ia');
        
        % 2. 计算指标（调用你的calc_metrics_one_run函数）
        out = calc_metrics_one_run(CA1, Te, ia);
        
        % 3. 绘制雷达图（调用你的draw_radar_from_workspace函数）
        draw_radar_from_workspace();
        uialert(app.UIFigure, 'Radar chart plotted successfully!', ...
        'Completed', 'Icon', 'success');

catch ME
    % Error handling
    err_msg = ME.message;

    % Provide suggestions based on error type
    if contains(err_msg, 'Te never enters')
        err_msg = ['Te response issue:' newline ...
                   '1. Increase the simulation time' newline ...
                   '2. Adjust the reference value (ref)' newline ...
                   '3. Increase the epsEnter parameter'];
               
    elseif contains(err_msg, 'Unrecognized variable')
        err_msg = ['Missing data:' newline ...
                   'Please run the Simulink simulation first to generate ' ...
                   'CA1, Te, and ia variables.'];
               
    elseif contains(err_msg, 'exist')
        err_msg = ['Variable not found:' newline ...
                   'Make sure the simulation has been executed and the data has been saved.'];
    end

    uialert(app.UIFigure, err_msg, 'Error', 'Icon', 'error');
end


        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
if isempty(app.BaseFigSize)
        return;
    end

    figW = app.UIFigure.Position(3);
    figH = app.UIFigure.Position(4);

    % ---- A) 大区域：用比例缩放（只用于左侧 tuner + 4个scope + sim控件） ----
    newSize = [figW figH];
    sx = newSize(1) / app.BaseFigSize(1);
    sy = newSize(2) / app.BaseFigSize(2);
    scale = @(p) [p(1)*sx, p(2)*sy, p(3)*sx, p(4)*sy];

    app.VariableTuner.Position      = scale(app.BasePos.VariableTuner);
    app.SimulationControls.Position = scale(app.BasePos.SimulationControls);
    app.SimulationProgress.Position = scale(app.BasePos.SimulationProgress);

    app.TimeScope.Position   = scale(app.BasePos.TimeScope);
    app.TimeScope_2.Position = scale(app.BasePos.TimeScope_2);
    app.TimeScope_3.Position = scale(app.BasePos.TimeScope_3);
    app.TimeScope_4.Position = scale(app.BasePos.TimeScope_4);

    % 更新左侧区域边界（用“缩放后的” VariableTuner）
    leftEdge = app.VariableTuner.Position(1) + app.VariableTuner.Position(3);
    m = app.Margin;
    gap = 12;

   % ---- B) 顶部：logo + 标题 全部锚点（不参与scale） ----
topMargin = 10;
rightMargin = 10;
gapLogo = 10;

% 右上：ipower 在最右，AAU 在它左侧（你截图就是这个顺序）
pIP  = app.BasePos.Image_2;  % ipower
pAAU = app.BasePos.Image_3;  % AAU

xIP  = figW - rightMargin - pIP(3);
yIP  = figH - topMargin - pIP(4);
app.Image_2.Position = [xIP, yIP, pIP(3), pIP(4)];

xAAU = xIP - gapLogo - pAAU(3);
yAAU = figH - topMargin - pAAU(4);
app.Image_3.Position = [xAAU, yAAU, pAAU(3), pAAU(4)];

% 标题顶部居中（保持不变）
pT = app.BasePos.TextArea_2;
titleTopMargin = 20;
xT = (figW - pT(3))/2;
yT = figH - titleTopMargin - pT(4);
app.TextArea_2.Position = [xT, yT, pT(3), pT(4)];
    % ---- C) 底部栏：CA说明 | Radar | Version 统一布局，保证不重叠 ----
    yBar = m;  % 底部栏贴底边距

    % 左：CA说明
    pL = app.BasePos.TextArea_3;
    app.TextArea_3.Position = [m, yBar, pL(3), pL(4)];

    % 右：Version
    pV = app.BasePos.TextArea;
    xV = figW - m - pV(3);
    app.TextArea.Position = [xV, yBar, pV(3), pV(4)];

    % 中：Radar（居中，空间不够就抬高一行）
    pR = app.BasePos.RadarPlotButton;
    xR_center = (figW - pR(3))/2;

    leftLimit  = m + pL(3) + gap;
    rightLimit = xV - gap - pR(3);

    if rightLimit < leftLimit
        % 放不下，抬高一行
        yR = yBar + max(pL(4), pV(4)) + gap;
        xR = max(m, xR_center);
    else
        yR = yBar;
        xR = min(max(xR_center, leftLimit), rightLimit);
    end
    app.RadarPlotButton.Position = [xR, yR, pR(3), pR(4)];

    % ---- D) About & Help：固定左上角（不参与scale） ----
   leftMargin = 10;
   topMargin  = 10;
   gapY = 6;  % 两个按钮之间的间距

   % About
   pA = app.BasePos.AboutButton;
   xA = leftMargin;
   yA = figH - topMargin - pA(4);
   app.AboutButton.Position = [xA, yA, pA(3), pA(4)];

  % Help（在 About 下方）
  pH = app.BasePos.HelpButton;
  xH = leftMargin;
  yH = yA - gapY - pH(4);
  app.HelpButton.Position = [xH, yH, pH(3), pH(4)];


    % ---- E) 底部模型图：放在右侧区域，且永远在底部栏上方，不和按钮重叠 ----
    % 预留底部栏高度（包括 radar 可能抬高一行后的最高点）
    barTop = max(yBar + pL(4), yBar + pV(4));
    barTop = max(barTop, yR + pR(4));   % radar 可能在第二行

    xImg = leftEdge + m;
    wImg = max(200, figW - xImg - m);

    yImg = barTop + gap;                % 图片底部 = 底部栏顶部 + 间隙
    hImg = max(120, 0.25 * figH);
    hMax = max(60, figH - yImg - m);
    hImg = min(hImg, hMax);

    app.Image.Position = [xImg, yImg, wImg, hImg];


        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
          % app1.mlapp 所在目录
    appFolder = fileparts(mfilename('fullpath'));

    % PDF 文件名（与你真实文件完全一致）
    pdfFile = fullfile(appFolder, 'Radar_Plots.pdf');

    if exist(pdfFile, 'file')
        open(pdfFile);   % 调用系统默认 PDF 阅读器
    else
        uialert(app.UIFigure, ...
            sprintf('PDF file not found:\n%s', pdfFile), ...
            'File Not Found');
    end

        end

        % Callback function: SimulationControls
        function SimulationControlsSizeChanged(app, event)
       %
        end

        % Callback function
        function ExporttoworkspaceButtonPushed(app, event)
%             % 1) 确保仿真已经停止
% st = get_param(app.Simulation.ModelName, "SimulationStatus");
% if ~strcmpi(st,"stopped")
%     error("Simulation is not stopped yet.");
% end
% 
% % 2) 尝试从 Simulation 对象拿到输出（不同版本字段名可能不同）
% out = [];
% if isprop(app.Simulation, "SimulationOutput")
%     out = app.Simulation.SimulationOutput;
% elseif isprop(app.Simulation, "Output")
%     out = app.Simulation.Output;
% end
% 
% % 3) 从 out 中取 To Workspace 的变量，再写回 base
% % （字段名是否存在取决于你的设置/版本）
% try, assignin("base","Te",  out.Te);  end
% try, assignin("base","ia",  out.ia);  end
% try, assignin("base","CA1", out.CA1); end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             % Stop and delete timer safely
    try
        if ~isempty(app.DataCheckTimer) && isvalid(app.DataCheckTimer)
            stop(app.DataCheckTimer);
            delete(app.DataCheckTimer);
        end
    catch
    end

    % Delete the app (required)
    delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.VariableTuner = uisimvartuner(app.UIFigure);
            app.VariableTuner.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'Wm'};
            app.TimeScope.Title = 'Wm';
            app.TimeScope.YLabel = 'Speed (p.u.)';
            app.TimeScope.XLimits = [0 4];
            app.TimeScope.YLimits = [-1 2];
            app.TimeScope.YLimitsMethod = 'manual';
            app.TimeScope.XTimeSpan = 1;
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationControls.SizeChangedFcn = createCallbackFcn(app, @SimulationControlsSizeChanged, true);
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.TimeScope_2 = uitimescope(app.UIFigure);
            app.TimeScope_2.LegendNames = {'Id'};
            app.TimeScope_2.Title = 'Id';
            app.TimeScope_2.YLabel = 'Id (p.u.)';
            app.TimeScope_2.XLimits = [0 4];
            app.TimeScope_2.YLimits = [-1 2];
            app.TimeScope_2.YLimitsMethod = 'manual';
            app.TimeScope_2.XTimeSpan = 1;
            app.TimeScope_3 = uitimescope(app.UIFigure);
            app.TimeScope_3.LegendNames = {'Iq'};
            app.TimeScope_3.Title = 'Iq';
            app.TimeScope_3.YLabel = 'Iq (p.u.)';
            app.TimeScope_3.XLimits = [0 4];
            app.TimeScope_3.YLimits = [-1 2];
            app.TimeScope_3.YLimitsMethod = 'manual';
            app.TimeScope_3.XTimeSpan = 1;
            app.TimeScope_4 = uitimescope(app.UIFigure);
            app.TimeScope_4.LegendNames = {'Tm/Te'};
            app.TimeScope_4.Title = 'Torque';
            app.TimeScope_4.YLabel = 'Torque (p.u.)';
            app.TimeScope_4.XLimits = [0 4];
            app.TimeScope_4.YLimits = [-1 2];
            app.TimeScope_4.YLimitsMethod = 'manual';
            app.TimeScope_4.XTimeSpan = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PMSMdiagram.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Version 1.1'; '© 2026 Yuan Li'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'PMSM Control Dashboard'};
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'ipower_ai_logo.jpg');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'AAU.png');
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'CA: 0 for PI'; '1 for MPC'; '2 for DPC'};
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RadarPlotButton = uibutton(app.UIFigure, 'push');
            app.RadarPlotButton.ButtonPushedFcn = createCallbackFcn(app, @RadarPlotButtonPushed, true);
            app.RadarPlotButton.Text = 'Radar Plot';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Constant1:1'], app.TimeScope_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Gain10:1'], app.TimeScope_3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Gain3:1'], app.TimeScope_4);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Gain4:1'], app.TimeScope_4);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Gain6:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Gain7:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Gain8:1'], app.TimeScope_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Gain9:1'], app.TimeScope_3);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Associate the Simulink Model
            app.Simulation = simulation('PMSMcontrolbenchmark');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end