classdef apptrain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataPreviewLabel                matlab.ui.control.Label
        ViewDataButton                  matlab.ui.control.Button
        ExportLossButton                matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        OpenPMSMSimulationButton        matlab.ui.control.Button
        ReferenceCurveButton            matlab.ui.control.Button
        AboutButton                     matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        AlgorithmEditField              matlab.ui.control.NumericEditField
        AlgorithmEditFieldLabel         matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        TextArea                        matlab.ui.control.TextArea
        BatchSizeEditField              matlab.ui.control.NumericEditField
        BatchSizeLabel_2                matlab.ui.control.Label
        LearningRateEditField           matlab.ui.control.NumericEditField
        LearningRateLabel_2             matlab.ui.control.Label
        LayerSizeNeuronsEditField       matlab.ui.control.EditField
        LayerSizeNeuronsEditFieldLabel  matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        EpochNumbersEditField           matlab.ui.control.NumericEditField
        EpochNumbersEditFieldLabel      matlab.ui.control.Label
        DataEndPointEditField           matlab.ui.control.NumericEditField
        DataEndPointEditFieldLabel      matlab.ui.control.Label
        DataStartPointEditField         matlab.ui.control.NumericEditField
        DataStartPointEditFieldLabel    matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        TrainButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         StopFlag = false;
    end

    properties (Access = private)
    LayoutInited logical = false
    BaseFigSize (1,2) double
    BasePos struct

    BaseFont struct              % <<< 新增：记录每个控件的基准字体
    FontMin double = 9           % <<< 新增：字体最小值（你可调）
    FontMax double = 26          % <<< 新增：字体最大值（你可调）
      PMSMApp
    TrainData struct = struct()   % 用户导入的训练数据（结构体/表都行）
    TrainDataPath string = ""     % 记录文件路径（可选）
    LastLossHistory double = []
    LastValLossHistory double = []
    LastEpochs double = []


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          % 关键：让 SizeChangedFcn 生效（否则被 MATLAB 自动布局接管）
    app.UIFigure.AutoResizeChildren = 'off';
    drawnow;
    app.AboutButton.Tooltip = ...
"Overview of the AI Control Trainer and evaluation methodology.";
    app.HelpButton.Tooltip = ...
"Guidelines for parameter settings and training options.";
app.OpenPMSMSimulationButton.Tooltip = ...
"Open the PMSM Simulink model for closed-loop simulation.";


    % 基准窗口尺寸
    app.BaseFigSize = app.UIFigure.Position(3:4);

    % ===== 统一登记：所有控件 Position + FontSize =====
    app.BasePos = struct();
    app.BaseFont = struct();

    % 把你想参与缩放的组件都列出来（基本就是全部）
    comps = { ...
            'UIAxes', ...
    'TrainButton','StopButton','HelpButton','AboutButton','ReferenceCurveButton','OpenPMSMSimulationButton', ...
    'LayerSizeNeuronsEditFieldLabel','LayerSizeNeuronsEditField', ...
    'DataStartPointEditFieldLabel','DataStartPointEditField', ...
    'DataEndPointEditFieldLabel','DataEndPointEditField', ...
    'EpochNumbersEditFieldLabel','EpochNumbersEditField', ...
    'LearningRateLabel_2','LearningRateEditField', ...
    'BatchSizeLabel_2','BatchSizeEditField', ...
    'AlgorithmEditFieldLabel','AlgorithmEditField', ...
    'StatusLabel', ...
    'TextArea','TextArea_2', ...
    'Image','Image_2','Image_3', 'LoadDataButton',  'ExportLossButton', 'DataPreviewLabel','ViewDataButton' ...
    };

    for k = 1:numel(comps)
        name = comps{k};
        if isprop(app, name)
            h = app.(name);

            % Position
            if isprop(h, 'Position')
                app.BasePos.(name) = h.Position;
            end

            % FontSize（不是所有组件都有）
            if isprop(h, 'FontSize')
                app.BaseFont.(name) = h.FontSize;
            end
        end
    end

    app.LayoutInited = true;

    % 启动时先跑一次，避免第一次不刷新
    UIFigureSizeChanged(app, []);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % --- choose training data source ---
useUserData = ~isempty(fieldnames(app.TrainData));

if ~useUserData
    % 默认数据（原逻辑）
    % e.g., load('PMSMdata2.mat') inside trainingapp
else
    % 用户数据会传进去
end

    app.StopFlag = false;   % reset stop flag
    numEpochs   = app.EpochNumbersEditField.Value;
    num1        = app.DataStartPointEditField.Value;
    num2        = app.DataEndPointEditField.Value;
    learningRate   = app.LearningRateEditField.Value;
    miniBatchSize  = app.BatchSizeEditField.Value;
    layerString = app.LayerSizeNeuronsEditField.Value;
    AF = app.AlgorithmEditField.Value;

    [net, lossHistory, valLossHistory] = trainingapp(app, numEpochs, num1, num2, learningRate, miniBatchSize, layerString, AF, app.UIAxes, app.TrainData);

     % Store latest curves for exporting
app.LastLossHistory = lossHistory(:);
app.LastValLossHistory = valLossHistory(:);
app.LastEpochs = (1:numel(app.LastLossHistory)).'; 
    
    if app.StopFlag
        app.StatusLabel.Text = "Training Stopped by User";
    else
        app.StatusLabel.Text = "Training Completed!";
    end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopFlag = true;
            app.StatusLabel.Text = "Stopping...";
            drawnow;

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
           msg = sprintf([ ...
    'AI Control Trainer – Parameter Guide\n\n', ...
    'General:\n', ...
    '  • Algorithm (AF):\n', ...
    '      1 → DPC mode (model-based loss)\n', ...
    '      0 → RL mode  (reward-based loss)\n\n', ...
    'Training Settings:\n', ...
    '  • LayerSize     : hidden layer sizes, e.g. [16 16] or [8 8 8]\n', ...
    '  • DataStart     : start index of training data\n', ...
    '  • DataEnd       : end index of training data\n', ...
    '  • Epoch         : number of training epochs (commonly 500–2000)\n', ...
    '  • LearningRate  : step size for Adam optimizer (e.g., 1e-4)\n', ...
    '  • BatchSize     : mini-batch size (e.g., 128–512)\n\n', ...
    'Validation:\n', ...
    '  • 10%% of the selected samples are automatically used as validation\n', ...
    '  • The plot displays:\n', ...
    '      - Training Loss  (blue)\n', ...
    '      - Validation Loss (red)\n\n', ...
      'Algorithm Details:\n', ...
    '  • DPC mode:\n', ...
    '      Loss = tracking error over 5 prediction steps\n', ...
    '  • RL mode:\n', ...
    '      Reward = -tracking error\n', ...
    '      Loss   = -Reward (policy-gradient style learning)\n' ...
]);
uialert(app.UIFigure, msg, 'Toolbox Help', 'Icon','info');
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msg = sprintf([ ...
        'AI Control Trainer – Overview\n\n', ...
        '1. Purpose\n', ...
        '  AI Control Trainer is a MATLAB App for training and ', ...
        'evaluating AI-based controllers for PMSM drives.\n', ...
        '  It supports both model-based Deep Predictive Control (DPC) ', ...
        'and Reinforcement Learning (RL).\n\n', ...
        '2. Main Functions\n', ...
        '  • Train neural-network controllers using PMSMdata2.mat.\n', ...
        '  • Switch between DPC (AF = 1) and RL (AF = 0).\n', ...
        '  • Customize network architecture (LayerSize).\n', ...
        '  • Tune training hyperparameters (Epoch, LearningRate, BatchSize).\n', ...
        '  • Automatic train / validation split (10%% for validation).\n', ...
        '  • Plot training and validation loss for convergence analysis.\n\n', ...
        '3. Typical Workflow\n', ...
        '  1) Choose LayerSize (e.g. [16 16] or [8 8 8]).\n', ...
        '  2) Set DataStart and DataEnd to select samples from PMSMdata2.mat.\n', ...
        '  3) Set Epoch, LearningRate and BatchSize.\n', ...
        '  4) Choose Algorithm AF: 1 = DPC, 0 = RL.\n', ...
        '  5) Click TrainButton and monitor the loss curves.\n\n', ...
        '4. Data Convention (PMSMdata2.mat)\n', ...
        '  • id_m    : measured d-axis current.\n', ...
        '  • iq_m    : measured q-axis current.\n', ...
        '  • iq_ref  : reference q-axis current.\n', ...
        '  • omega_e : electrical angular speed.\n\n', ...
        '5. Intended Use\n', ...
        '  • Research on AI-assisted motor control and predictive control.\n', ...
        '  • Rapid prototyping of DPC / RL controllers in MATLAB.\n', ...
        '  • Teaching examples for data-driven control of power electronics.\n\n', ...
        'Version 1.0  (2025-11)\n', ...
        'Author: Yuan Li\n' ...
    ]);

    % 弹出一个带滚动条的说明窗口
    uialert(app.UIFigure, msg, 'About AI Control Trainer', 'Icon','info');
        end

        % Button pushed function: ReferenceCurveButton
        function ReferenceCurveButtonPushed(app, event)
         try
        % 加载训练数据
        data = load('training_data.mat');
        
        % 清空当前axes
        cla(app.UIAxes);
        
        % 重新绘制曲线（矢量图，永远高清）
        plot(app.UIAxes, data.epochs, data.training_loss, ...
             'Color', [0.00, 0.45, 0.74], 'LineWidth', 2);
        hold(app.UIAxes, 'on');
        plot(app.UIAxes, data.epochs, data.validation_loss, ...
             'Color', [0.85, 0.33, 0.10], 'LineWidth', 2);
        hold(app.UIAxes, 'off');
        
        % 设置图表属性
        grid(app.UIAxes, 'on');
        xlabel(app.UIAxes, 'Epoch', 'FontSize', 11);
        ylabel(app.UIAxes, 'Loss', 'FontSize', 11);
        title(app.UIAxes, 'Reference Training Curve (Epoch 2000/2000)', ...
              'FontSize', 12, 'FontWeight', 'bold');
        legend(app.UIAxes, {'Training Loss', 'Validation Loss'}, ...
               'Location', 'northeast');
        xlim(app.UIAxes, [0 2000]);
        
    catch ME
        uialert(app.UIFigure, ...
                'Reference data not found. Please generate it first.', ...
                'File Not Found');
    end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
if ~app.LayoutInited
        return
    end

    figW = app.UIFigure.Position(3);
    figH = app.UIFigure.Position(4);

    sx = figW / app.BaseFigSize(1);
    sy = figH / app.BaseFigSize(2);

    % 字体建议用“统一比例”，避免横向拉伸导致字体怪
    sFont = min(sx, sy);

    % ===== 1) 缩放所有控件 Position =====
    names = fieldnames(app.BasePos);
    for k = 1:numel(names)
        name = names{k};
        if isprop(app, name)
            h = app.(name);
            p0 = app.BasePos.(name);

            % 统一缩放（x,y,w,h）
            p = [p0(1)*sx, p0(2)*sy, p0(3)*sx, p0(4)*sy];

            % 给按钮/输入框一个最小高度，避免窗口变小时挤没
            if contains(name,'Button') || contains(name,'EditField') || contains(name,'Label') || contains(name,'TextArea')
                p(4) = max(p(4), 22);
            end

            % UIAxes 也给个最小尺寸
            if strcmp(name,'UIAxes')
                p(3) = max(p(3), 260);
                p(4) = max(p(4), 180);
            end

            if isprop(h,'Position')
                h.Position = p;
            end
        end
    end

    % ===== 2) 缩放所有控件 FontSize =====
    fn = fieldnames(app.BaseFont);
    for k = 1:numel(fn)
        name = fn{k};
        if isprop(app, name)
            h = app.(name);
            fs0 = app.BaseFont.(name);

            fs = fs0 * sFont;
            fs = min(max(fs, app.FontMin), app.FontMax);

            if isprop(h,'FontSize')
                h.FontSize = fs;
            end
        end
    end

    % ===== 3) UIAxes 额外：标题/坐标轴字体跟着变（否则只变 tick）=====
    % 注意：这些对象也有 FontSize
    try
        fsA = app.UIAxes.FontSize;           % 已经缩放过
        app.UIAxes.Title.FontSize  = fsA + 2;
        app.UIAxes.XLabel.FontSize = fsA;
        app.UIAxes.YLabel.FontSize = fsA;
    catch
    try
    xCol = app.ReferenceCurveButton.Position(1);  % 以 ReferenceCurve 的 x 为准
    app.OpenPMSMSimulationButton.Position(1) = xCol;
    app.HelpButton.Position(1) = xCol;
    app.AboutButton.Position(1) = xCol;
    catch
    end
    end
    % ===== 4) Special layout for Image_3 (responsive, no overlap) =====
try
    % 让图片按区域自适应填充
    app.Image_3.ScaleMethod = "fit";

    % 右侧区域以 UIAxes 的 x 和 width 为基准（避免压到左侧控件）
    axP = app.UIAxes.Position;  % [x y w h]

    % 设一些边距（随窗口缩放）
    gap = 12 * min(sx, sy);     % UIAxes 与图片间距
    bottomMargin = 40 * sy;     % 给底部 Version 文本留空间

    x = axP(1);
    w = axP(3);

    % 图片区域：从底部 margin 到 UIAxes 下方 gap
    y = bottomMargin;
    top = axP(2) - gap;
    h = max(top - y, 80);       % 给个最小高度，防止窗口太小时变负

    app.Image_3.Position = [x y w h];
catch
end
% ---- Align big buttons in left column ----
try
    bx = 57*sx;
    bw = 129*sx;
    for nm = {'TrainButton','StopButton','LoadDataButton','ExportLossButton'}
        h = app.(nm{1});
        p = h.Position;
        h.Position = [bx, p(2), bw, p(4)];
    end
catch
end

% ---- Align Data Preview area ----
try
    app.DataPreviewLabel.Position = [64*sx, 4*sy, 76*sx, 22];
    app.ViewDataButton.Position   = [148*sx, 4*sy, 100*sx, 22];
catch
end

            
        end

        % Button pushed function: OpenPMSMSimulationButton
        function OpenPMSMSimulationButtonPushed(app, event)
               % 如果已经打开，就直接置顶
    if ~isempty(app.PMSMApp) ...
            && isvalid(app.PMSMApp) ...
            && isvalid(app.PMSMApp.UIFigure)

        figure(app.PMSMApp.UIFigure);
        return;
    end

    % 打开 app1.mlapp（类名是 app1）
    app.PMSMApp = app1();

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
      [file, path] = uigetfile({'*.mat','MAT-files (*.mat)'}, ...
                             'Select training data (.mat)');
    if isequal(file,0)
        return;
    end

    fullpath = fullfile(path, file);
    app.TrainDataPath = string(fullpath);

    try
        S = load(fullpath);

        % ===== 数据格式检查 =====
        hasDataOut = isfield(S, 'data_out');
        hasSeparateVars = isfield(S, 'id_m') && isfield(S, 'iq_m') && ...
                          isfield(S, 'iq_ref') && isfield(S, 'omega_e');
        
        % 检查是否至少有一种格式
        if ~hasDataOut && ~hasSeparateVars
            error(['Invalid data format.\n\n' ...
                   'Required format (choose one):\n' ...
                   '1) Variable "data_out" (N×4): [id_m, iq_m, iq_ref, omega_e]\n' ...
                   '2) Four separate vectors: id_m, iq_m, iq_ref, omega_e']);
        end
        
        % 如果有 data_out，检查维度
        if hasDataOut
            [nRows, nCols] = size(S.data_out);
            if nCols ~= 4
                error(['"data_out" must be N×4 matrix.\n' ...
                       'Expected columns: [id_m, iq_m, iq_ref, omega_e]\n' ...
                       'Current size: %d×%d'], nRows, nCols);
            end
            
            N = nRows;
            formatInfo = sprintf('data_out (%d×4)', N);
            
        else
            % 检查四个向量长度是否一致
            len_id = numel(S.id_m);
            len_iq = numel(S.iq_m);
            len_ref = numel(S.iq_ref);
            len_omega = numel(S.omega_e);
            
            if ~(len_id == len_iq && len_iq == len_ref && len_ref == len_omega)
                error(['All vectors must have the same length.\n' ...
                       'Current lengths:\n' ...
                       '  id_m:    %d\n' ...
                       '  iq_m:    %d\n' ...
                       '  iq_ref:  %d\n' ...
                       '  omega_e: %d'], ...
                       len_id, len_iq, len_ref, len_omega);
            end
            
            N = len_id;
            formatInfo = sprintf('4 vectors (%d samples)', N);
        end
        
        % ===== 数据加载成功 =====
        app.TrainData = S;
        
        % 自动更新 DataEndPoint
        app.DataEndPointEditField.Value = N;
        
        % 更新状态标签（带格式信息）
        app.StatusLabel.Text = sprintf('✓ Loaded: %s', formatInfo);
        
        % 可选：弹出详细信息
        msg = sprintf(['Data loaded successfully!\n\n' ...
                       'File: %s\n' ...
                       'Format: %s\n' ...
                       'Total samples: %d\n\n' ...
                       'DataEndPoint has been set to %d automatically.'], ...
                       file, formatInfo, N, N);
        uialert(app.UIFigure, msg, 'Data Loaded', 'Icon', 'success');

    catch ME
        % 加载失败，清空数据
        app.TrainData = struct();
        app.TrainDataPath = "";
        
        % 更新状态标签
        app.StatusLabel.Text = '✗ Load failed';
        
        % 显示详细错误信息
        errorMsg = sprintf(['Failed to load training data:\n\n%s\n\n' ...
                           '──────────────────────────\n' ...
                           'Required data format:\n\n' ...
                           'Option 1: Single matrix\n' ...
                           '  • Variable name: "data_out"\n' ...
                           '  • Size: N×4\n' ...
                           '  • Columns: [id_m, iq_m, iq_ref, omega_e]\n\n' ...
                           'Option 2: Separate vectors\n' ...
                           '  • Four variables: id_m, iq_m, iq_ref, omega_e\n' ...
                           '  • All must have the same length'], ...
                           ME.message);
        uialert(app.UIFigure, errorMsg, 'Load Error', 'Icon', 'error');
    end

    % ---- Align big buttons in left column ----
try
    bx = 57*sx;          % 左侧按钮列 x
    bw = 129*sx;         % 宽度统一
    for nm = {'TrainButton','StopButton','LoadDataButton'}
        h = app.(nm{1});
        p = h.Position;
        h.Position = [bx, p(2), bw, p(4)];
    end
catch
end

        end

        % Button pushed function: ExportLossButton
        function ExportLossButtonPushed(app, event)
            % ---- basic check ----
    if isempty(app.LastLossHistory)
        uialert(app.UIFigure, "No loss curve available. Please run training first.", "Export Error");
        return;
    end

    % ---- ask user where to save figure ----
    [file, path] = uiputfile( ...
        {'*.png','PNG Image (*.png)'; ...
         '*.pdf','PDF Vector (*.pdf)'; ...
         '*.svg','SVG Vector (*.svg)'}, ...
        'Save loss curve as', 'loss_curve.png');

    if isequal(file,0)
        return;
    end

    figPath = fullfile(path, file);

    try
        % ---- export figure (axes only) ----
        [~,~,ext] = fileparts(figPath);
        if strcmpi(ext,'.png')
            exportgraphics(app.UIAxes, figPath, 'Resolution', 300);
        else
            % pdf/svg: vector export
            exportgraphics(app.UIAxes, figPath);
        end

        % ---- also save data (.mat) with same prefix ----
        [p, n, ~] = fileparts(figPath);
        dataPath = fullfile(p, n + "_loss_data.mat");

        loss.epochs = app.LastEpochs;
        loss.train  = app.LastLossHistory;
        loss.val    = app.LastValLossHistory;
        loss.source = app.TrainDataPath;  % 可选：记录数据来源路径

        save(dataPath, 'loss');

        app.StatusLabel.Text = "Exported: " + string(file) + " (+ loss_data.mat)";

    catch ME
        uialert(app.UIFigure, "Export failed: " + ME.message, "Export Error");
    end
        end

        % Button pushed function: ViewDataButton
        function ViewDataButtonPushed(app, event)
            if isempty(fieldnames(app.TrainData))
        uialert(app.UIFigure, ...
            "No data loaded. Please click LoadData first.", ...
            "No Data");
        return;
    end

    S = app.TrainData;

    try

        if isfield(S, 'data_out')
            data_out = S.data_out;

            id_m    = data_out(:,1);
            iq_m    = data_out(:,2);
            iq_ref  = data_out(:,3);
            omega_e = data_out(:,4);

        else

            id_m    = S.id_m(:);
            iq_m    = S.iq_m(:);
            iq_ref  = S.iq_ref(:);
            omega_e = S.omega_e(:);

        end

        N = numel(id_m);
        Nplot = min(3000, N);

        idx = round(linspace(1, N, Nplot));

        cla(app.UIAxes);

        plot(app.UIAxes, idx, id_m(idx), 'LineWidth', 1.2);

        hold(app.UIAxes, 'on');

        plot(app.UIAxes, idx, iq_m(idx), 'LineWidth', 1.2);

        plot(app.UIAxes, idx, iq_ref(idx), 'LineWidth', 1.2);

        plot(app.UIAxes, idx, omega_e(idx), 'LineWidth', 1.2);

        hold(app.UIAxes, 'off');

        grid(app.UIAxes, 'on');

        title(app.UIAxes, 'Loaded Data Preview');

        xlabel(app.UIAxes, 'Sample Index');

        ylabel(app.UIAxes, 'Value');

        legend(app.UIAxes, ...
            {'i_d', 'i_q', 'i_q^*', '\omega_e'}, ...
            'Location', 'best');

        app.StatusLabel.Text = "Data preview displayed";

    catch ME

        uialert(app.UIFigure, ...
            "Failed to display data: " + ME.message, ...
            "View Data Error");

    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Training and Validation Loss')
            xlabel(app.UIAxes, 'Epoch')
            ylabel(app.UIAxes, 'Loss')
            zlabel(app.UIAxes, 'Z')
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'TrainButton';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'StatusLabel';
            app.DataStartPointEditFieldLabel = uilabel(app.UIFigure);
            app.DataStartPointEditFieldLabel.Text = 'Data Start Point';
            app.DataStartPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.DataStartPointEditField.Value = 1;
            app.DataEndPointEditFieldLabel = uilabel(app.UIFigure);
            app.DataEndPointEditFieldLabel.Text = 'Data End Point';
            app.DataEndPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.DataEndPointEditField.Value = 10000;
            app.EpochNumbersEditFieldLabel = uilabel(app.UIFigure);
            app.EpochNumbersEditFieldLabel.Text = 'Epoch Numbers';
            app.EpochNumbersEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpochNumbersEditField.Value = 2000;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'StopButton';
            app.LayerSizeNeuronsEditFieldLabel = uilabel(app.UIFigure);
            app.LayerSizeNeuronsEditFieldLabel.Text = 'Layer Size/Neurons';
            app.LayerSizeNeuronsEditField = uieditfield(app.UIFigure, 'text');
            app.LayerSizeNeuronsEditField.Value = '[16 16]';
            app.LearningRateLabel_2 = uilabel(app.UIFigure);
            app.LearningRateLabel_2.Text = 'LearningRate';
            app.LearningRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.LearningRateEditField.Value = 0.0001;
            app.BatchSizeLabel_2 = uilabel(app.UIFigure);
            app.BatchSizeLabel_2.Text = 'BatchSize';
            app.BatchSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BatchSizeEditField.Value = 512;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'AI Control Trainer'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ipower_ai_logo.jpg');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'AAU.png');
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Version 1.1'; '© 2026 Yuan Li'};
            app.AlgorithmEditFieldLabel = uilabel(app.UIFigure);
            app.AlgorithmEditFieldLabel.Text = 'Algorithm';
            app.AlgorithmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.ReferenceCurveButton = uibutton(app.UIFigure, 'push');
            app.ReferenceCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceCurveButtonPushed, true);
            app.ReferenceCurveButton.Text = 'Reference Curve';
            app.OpenPMSMSimulationButton = uibutton(app.UIFigure, 'push');
            app.OpenPMSMSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPMSMSimulationButtonPushed, true);
            app.OpenPMSMSimulationButton.Text = 'Open PMSM Simulation';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Workflowtraining.png');
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'LoadData';
            app.ExportLossButton = uibutton(app.UIFigure, 'push');
            app.ExportLossButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLossButtonPushed, true);
            app.ExportLossButton.Text = 'ExportLoss';
            app.ViewDataButton = uibutton(app.UIFigure, 'push');
            app.ViewDataButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDataButtonPushed, true);
            app.ViewDataButton.Text = 'View Data';
            app.DataPreviewLabel = uilabel(app.UIFigure);
            app.DataPreviewLabel.Text = 'Data Preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptrain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end